classdef vid_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CloseApplicationButton      matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        GeneralTab                  matlab.ui.container.Tab
        StartButton                 matlab.ui.control.Button
        AbortButton                 matlab.ui.control.Button
        VideoCaptureInProgressLampLabel  matlab.ui.control.Label
        VideoCaptureInProgressLamp  matlab.ui.control.Lamp
        FileNameEditFieldLabel      matlab.ui.control.Label
        FileNameEditField           matlab.ui.control.EditField
        DurationsEditFieldLabel     matlab.ui.control.Label
        DurationsEditField          matlab.ui.control.NumericEditField
        ParametersTab               matlab.ui.container.Tab
        UpdateParametersButton      matlab.ui.control.Button
        ExposureModeDropDownLabel   matlab.ui.control.Label
        ExposureModeDropDown        matlab.ui.control.DropDown
        GainEditFieldLabel          matlab.ui.control.Label
        GainEditField               matlab.ui.control.NumericEditField
        GammaEditFieldLabel         matlab.ui.control.Label
        GammaEditField              matlab.ui.control.NumericEditField
        BrightnessEditFieldLabel    matlab.ui.control.Label
        BrightnessEditField         matlab.ui.control.NumericEditField
        FrameRateModeDropDownLabel  matlab.ui.control.Label
        FrameRateModeDropDown       matlab.ui.control.DropDown
        ShutterModeDropDownLabel    matlab.ui.control.Label
        ShutterModeDropDown         matlab.ui.control.DropDown
        ExposureTimeEditFieldLabel  matlab.ui.control.Label
        ExposureTimeEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        Video = vid_config();
        vid;
        fid;
        abort = false;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.VideoCaptureInProgressLamp.Enable = 'off';
            
            %app.vid = videoinput('winvideo', app.videoInputNumber);
            app.vid = videoinput('pointgrey', 2, 'F7_Raw16_1024x768_Mode2');
            
            % m = load([INSERT FILEPATH TO EXISTING PROPERTIES HERE!]);
            app.ExposureModeDropDown.Value = app.Video.ExposureMode;
            app.FrameRateModeDropDown.Value = app.Video.FrameRateMode;
            app.GainEditField.Value = app.Video.Gain;
            app.GammaEditField.Value = app.Video.Gamma;
            app.BrightnessEditField.Value = app.Video.Brightness;
            app.ShutterModeDropDown.Value = app.Video.ShutterMode;
            app.ExposureTimeEditField.Value = app.Video.ExposureTime;
            app.DurationsEditField.Value = 60;
            
            src = getselectedsource(app.vid); 
            src.ExposureMode = app.Video.ExposureMode;
            src.FrameRateMode = app.Video.FrameRateMode;
            src.ShutterMode = app.Video.ShutterMode;
            src.Gain = app.Video.Gain;
            src.Gamma = app.Video.Gamma;
            src.Brightness = app.Video.Brightness;
            src.Shutter = app.Video.ExposureTime;
            
            triggerconfig(app.vid, 'manual');
            
            vidRes = app.vid.VideoResolution;
            
            imageRes = fliplr(vidRes);
    
            f = figure('Visible', 'off', 'Units', 'normalized');
            ax = subplot(2, 1, 1);
            set(ax, 'Units', 'normalized');
            set(ax, 'Position', [0.05, 0.4515, .9, 0.53]); 
            
            hImage = imshow(uint16(zeros(imageRes)));
            axis image
            
            hImage.UserData{1} = false;
            hImage.UserData{2} = false;
            
            setappdata(hImage, 'UpdatePreviewWindowFcn', @vid_livehist_new);
        %     hImage.CData = log10(double(hImage.CData));
            h = preview(app.vid, hImage);
            set(h, 'CDataMapping', 'scaled');
        
            switch nargout
                case 1
                    varargout{1} = f;
            end
            
            disp('Ready to begin video capture')
            
        end

        % Button pushed function: CloseApplicationButton
        function CloseApplicationButtonPushed(app, event)
            close all
            closereq
        end

        % Button pushed function: UpdateParametersButton
        function UpdateParametersButtonPushed(app, event)
            close all
            
            src.ExposureMode = app.ExposureModeDropDown.Value;
            src.FrameRateMode = app.FrameRateModeDropDown.Value;
            src.ShutterMode = app.ShutterModeDropDown.Value;
            src.Gain = app.GammaEditField.Value;
            src.Gamma = app.GammaEditField.Value;
            src.Brightness = app.BrightnessEditField.Value;
            src.Shutter = app.ExposureTimeEditField.Value;
            
            vidRes = app.vid.VideoResolution;
            
            imageRes = fliplr(vidRes);
    
            f = figure('Visible', 'off', 'Units', 'normalized');
            ax = subplot(2, 1, 1);
            set(ax, 'Units', 'normalized');
            set(ax, 'Position', [0.05, 0.4515, .9, 0.53]); 
            
            hImage = imshow(uint16(zeros(imageRes)));
            axis image
        
            hImage.UserData{1} = false;
            hImage.UserData{2} = false;
            
            setappdata(hImage, 'UpdatePreviewWindowFcn', @vid_livehist_new);
        %     hImage.CData = log10(double(hImage.CData));
            h = preview(app.vid, hImage);
            set(h, 'CDataMapping', 'scaled');
        
            switch nargout
                case 1
                    varargout{1} = f;
            end
            
            disp('Video capture parameters have been successfully updated')
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            % Turning on the vid capture lamp
            app.VideoCaptureInProgressLamp.Enable = 'on';
            
            % Setting Frames Per Trigger
            Fps = 1 / (app.Video.ExposureTime/1000);
            app.vid.FramesPerTrigger = Fps * app.DurationsEditField.Value;
            
            % Start Video
            start(app.vid)
     
            % Opening the bin file
            binfilename = [app.FileNameEditField.Value,'.bin'];
            if ~isempty(dir(binfilename))
                error('That file already exists. Change the filename and try again.');
            end
            
            app.fid = fopen(binfilename, 'w');
            
            NFramesTaken = 0;
            NFramesAvailable = 0;
            cnt = 0;
            trigger(app.vid);
            
            disp('Beginning video capture...')
            
            % Taking the video
            while(NFramesTaken < app.vid.FramesPerTrigger)
       
%                 if app.vid.FramesAvailable==0
%                     disp('Looping through.');
%                     continue;
%                 end

                if app.abort == true
                    disp('Video capture aborted')
                    app.abort = false;
                    break
                end

                cnt = cnt + 1;
                
                NFramesAvailable(cnt,1) = app.vid.FramesAvailable;
                NFramesTaken = NFramesTaken + NFramesAvailable(cnt,1);
                %disp(['Num Grabbed Frames: ' num2str(NFramesAvailable(cnt,1)) '/' num2str(NFramesTaken)]);
            
                [data, ~, meta] = getdata(app.vid, NFramesAvailable(cnt,1));    
                    
                fwrite(app.fid, data, 'uint16');
            
                if ~mod(cnt,5)
                    drawnow;
                end


            end
            
            % Stops video collection
            app.VideoCaptureInProgressLamp.Enable = 'off';
            fclose(app.fid);
            clear app.fid
            stop(app.vid);
            
            disp('Video capture complete')
            disp('Ready to begin video capture')
            
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            app.abort = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CloseApplicationButton = uibutton(app.UIFigure, 'push');
            app.CloseApplicationButton.ButtonPushedFcn = createCallbackFcn(app, @CloseApplicationButtonPushed, true);
            app.CloseApplicationButton.Text = 'Close Application';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.StartButton = uibutton(app.GeneralTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.AbortButton = uibutton(app.GeneralTab, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.VideoCaptureInProgressLampLabel = uilabel(app.GeneralTab);
            app.VideoCaptureInProgressLampLabel.Text = 'Video Capture In Progress';
            app.VideoCaptureInProgressLamp = uilamp(app.GeneralTab);
            app.FileNameEditFieldLabel = uilabel(app.GeneralTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.GeneralTab, 'text');
            app.DurationsEditFieldLabel = uilabel(app.GeneralTab);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.GeneralTab, 'numeric');
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.UpdateParametersButton = uibutton(app.ParametersTab, 'push');
            app.UpdateParametersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateParametersButtonPushed, true);
            app.UpdateParametersButton.Text = 'Update Parameters';
            app.ExposureModeDropDownLabel = uilabel(app.ParametersTab);
            app.ExposureModeDropDownLabel.Text = 'Exposure Mode';
            app.ExposureModeDropDown = uidropdown(app.ParametersTab);
            app.ExposureModeDropDown.Items = {'on', 'off'};
            app.ExposureModeDropDown.Value = 'on';
            app.GainEditFieldLabel = uilabel(app.ParametersTab);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.ParametersTab, 'numeric');
            app.GammaEditFieldLabel = uilabel(app.ParametersTab);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.ParametersTab, 'numeric');
            app.BrightnessEditFieldLabel = uilabel(app.ParametersTab);
            app.BrightnessEditFieldLabel.Text = 'Brightness';
            app.BrightnessEditField = uieditfield(app.ParametersTab, 'numeric');
            app.FrameRateModeDropDownLabel = uilabel(app.ParametersTab);
            app.FrameRateModeDropDownLabel.Text = 'Frame Rate Mode';
            app.FrameRateModeDropDown = uidropdown(app.ParametersTab);
            app.FrameRateModeDropDown.Items = {'on', 'off'};
            app.FrameRateModeDropDown.Value = 'on';
            app.ShutterModeDropDownLabel = uilabel(app.ParametersTab);
            app.ShutterModeDropDownLabel.Text = 'Shutter Mode';
            app.ShutterModeDropDown = uidropdown(app.ParametersTab);
            app.ShutterModeDropDown.Items = {'manual'};
            app.ShutterModeDropDown.Value = 'manual';
            app.ExposureTimeEditFieldLabel = uilabel(app.ParametersTab);
            app.ExposureTimeEditFieldLabel.Text = 'Exposure Time';
            app.ExposureTimeEditField = uieditfield(app.ParametersTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vid_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end