classdef vid_parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        OKButton                   matlab.ui.control.Button
        ExposureModeDropDownLabel  matlab.ui.control.Label
        ExposureModeDropDown       matlab.ui.control.DropDown
        GainEditFieldLabel         matlab.ui.control.Label
        GainEditField              matlab.ui.control.NumericEditField
        GammaEditFieldLabel        matlab.ui.control.Label
        GammaEditField             matlab.ui.control.NumericEditField
        BrightnessEditFieldLabel   matlab.ui.control.Label
        BrightnessEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Video = ba_config_video('Grasshopper3');
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ExposureModeDropDown.Value = app.Video.ExposureMode;
            app.GainEditField.Value = app.Video.Gain;
            app.GammaEditField.Value = app.Video.Gamma;
            app.BrightnessEditField.Value = app.Video.Brightness;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            m.ExposureMode = app.ExposureModeDropDown.Value;
            m.Gain = app.GammaEditField.Value;
            save('video_test.mat', 'm');
            closereq
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ExposureModeDropDownLabel = uilabel(app.UIFigure);
            app.ExposureModeDropDownLabel.Text = 'Exposure Mode';
            app.ExposureModeDropDown = uidropdown(app.UIFigure);
            app.ExposureModeDropDown.Items = {'on', 'off'};
            app.ExposureModeDropDown.Value = 'on';
            app.GainEditFieldLabel = uilabel(app.UIFigure);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.UIFigure, 'numeric');
            app.GammaEditFieldLabel = uilabel(app.UIFigure);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.UIFigure, 'numeric');
            app.BrightnessEditFieldLabel = uilabel(app.UIFigure);
            app.BrightnessEditFieldLabel.Text = 'Brightness';
            app.BrightnessEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = vid_parameters

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end